classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        KillProgramButton             matlab.ui.control.Button
        MapGeneratedLabel             matlab.ui.control.Label
        BluetoothConnectionLostLabel  matlab.ui.control.Label
        PathGeneratedLabel            matlab.ui.control.Label
        BTRead                        matlab.ui.control.Label
        BluetoothConnectionSuccessfulLabel  matlab.ui.control.Label
        ConnectBluetoothButton        matlab.ui.control.Button
        MapOutEnviornmentButton       matlab.ui.control.Button
        LocalizeinMapButton           matlab.ui.control.Button
        SendPathButton                matlab.ui.control.Button
        GeneratePathButton            matlab.ui.control.Button
        MapNameEditField              matlab.ui.control.EditField
        SaveMapButton                 matlab.ui.control.Button
        LoadMapButton                 matlab.ui.control.Button
        SendTopologicalPathButton     matlab.ui.control.Button
        TopologicalPathEditField      matlab.ui.control.EditField
        TopologicalPathLabel          matlab.ui.control.Label
        SelectModeDropDown            matlab.ui.control.DropDown
        SelectModeLabel               matlab.ui.control.Label
        EndLocationLabel              matlab.ui.control.Label
        EndLocationX_TextBox          matlab.ui.control.TextArea
        Label_4                       matlab.ui.control.Label
        EndLocationY_TextBox          matlab.ui.control.TextArea
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        StartLocationY_TextBox        matlab.ui.control.TextArea
        Label                         matlab.ui.control.Label
        StartLocationX_TextBox        matlab.ui.control.TextArea
        StartLocationLabel            matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        topPath % Stores the topological path
        mapTextFileName % Gives name of text file to load for map
        r1
        r2
%         l1
%         l2
        loadMapLines
        metricLines
%         l4
%         l5
        mappingLines
        delete_complete
        bt
        btConnected
        xStart
        yStart
        xGoal
        yGoal
        map
        metricPath
        runMapping;
    end
    
    methods (Access = private)
        
        function deleteLines(app)
%             for i = 1:4
%                 for j = 1:4
%                     delete(app.l4(app.UIAxes,i));
%                     delete(yline(app.UIAxes,j));
%                 end
%             end
            
%             clear app.UIAxes;

            delete(app.r1);
            delete(app.r2);
%             delete(app.l1);
%             delete(app.l2);
            delete(app.loadMapLines(:,:));
            delete(app.metricLines(:,:));
%             delete(app.l4);
%             delete(app.l5);
            delete(app.mappingLines(:,:));
            app.delete_complete = true; 
        end
        
        function plotMetricPath(app, path)
            app.delete_complete = false;
            x1 = str2num(cell2mat(app.xStart)) + 0.5;
            y1 = str2num(cell2mat(app.yStart)) + 0.5;
            linecount = 1;
            for i = 1:strlength(path)
                if (path(i) == 'N')
                    x2 = x1;
                    y2 = y1 + 1;
                elseif (path(i) == 'E')
                    x2 = x1 + 1;
                    y2 = y1;
                elseif (path(i) == 'S')
                    x2 = x1;
                    y2 = y1 - 1;
                elseif (path(i) == 'W')
                    x2 = x1 - 1;
                    y2 = y1;
                end
                app.metricLines(linecount) = line(app.UIAxes,[x1 x2],[y1 y2],'color','r','LineWidth',2);
                x1 = x2;
                y1 = y2;
                linecount = linecount + 1;
            end
        end
        
        function mapData(app, xLoc, yLoc, walls)
            [numRows,numCols] = size(app.map);
            axis(app.UIAxes,[-1 numCols+1 -1 numRows+1])
            
            linecount = 1;
            
            app.delete_complete = false;
            
            xLocNew = xLoc + 1;
            yLocNew = numRows - yLoc;
            app.map(yLocNew, xLocNew) = walls;
            
            for r = 1:numRows
                for c = 1:numCols
                    if(bitget(app.map(r,c),1)==1)
                        x1 = c-1; x2 = c;
                        y1 = numRows-(r-1); y2 = numRows-(r-1);
                        app.mappingLines(linecount) = line(app.UIAxes,[x1 x2],[y1 y2],'color','k','LineWidth',2);
                        linecount = linecount + 1;
                        drawnow;
                    end
                    if(bitget(app.map(r,c),2)==1)
                        x1 = c; x2 = c;
                        y1 = numRows-(r-1); y2 = numRows-r;
                        app.mappingLines(linecount) = line(app.UIAxes,[x1 x2],[y1 y2],'color','k','LineWidth',2);
                        linecount = linecount + 1;
                        drawnow;
                    end
                    if(bitget(app.map(r,c),3)==1)
                        x1 = c-1; x2 = c;
                        y1 = numRows-r; y2 = numRows-r;
                        app.mappingLines(linecount) = line(app.UIAxes,[x1 x2],[y1 y2],'color','k','LineWidth',2);
                        linecount = linecount + 1;
                        drawnow;
                    end
                    if(bitget(app.map(r,c),4)==1)
                        x1 = c-1; x2 = c-1;
                        y1 = numRows-(r-1); y2 = numRows-r;
                        app.mappingLines(linecount) = line(app.UIAxes,[x1 x2],[y1 y2],'color','k','LineWidth',2);
                        linecount = linecount + 1;
                        drawnow;
                    end
                end
            end
%             disp(app.map);
        end
        
        function drawRect(app)
            app.map = zeros(4,4);
            [numRows,numCols] = size(app.map);
            axis(app.UIAxes,[-1 numCols+1 -1 numRows+1])
            app.r2 = rectangle(app.UIAxes,'Position',[0 0 numCols numRows],'LineWidth',2);
            drawnow;
        end


        function sendLocPath(app, path)
%             write(app.bt,'~P');
%             pause(0.1);
            write(app.bt,append(path,'-'));
        end
    end
    
    methods (Access = public)
        
        function readLoc(app, xLoc, yLoc)
            path = '';
            path = findMetricPath(app.map, xLoc, yLoc, app.xGoal, app.yGoal);
            app.PathGeneratedLabel.Visible = 'on';   
            app.sendLocPath(path);
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.btConnected = false;
            app.delete_complete = false;
            app.runMapping = false;
        end

        % Value changed function: SelectModeDropDown
        function SelectModeDropDownValueChanged(app, event)
            value = app.SelectModeDropDown.Value;
            if (~app.delete_complete)
                deleteLines(app);
                app.mapTextFileName = '';
                app.MapNameEditField.Value = '';
            end
            % Make bluetooth connection button visible
            if (~app.btConnected)
                app.ConnectBluetoothButton.Visible = 'on';
            end   
            
            % Turn off visibility of everything 
            % Make start location not visible
            app.StartLocationLabel.Visible = 'off';
            app.StartLocationX_TextBox.Visible = 'off';
            app.Label.Visible = 'off';
            app.StartLocationY_TextBox.Visible = 'off';
            app.Label_2.Visible = 'off';
            % Make end location not visible
            app.EndLocationLabel.Visible = 'off';
            app.EndLocationX_TextBox.Visible = 'off';
            app.Label_4.Visible = 'off';
            app.EndLocationY_TextBox.Visible = 'off';
            app.Label_3.Visible = 'off';
            % Make Topological Path boxes not visible
            app.topPath = '';
            app.TopologicalPathEditField.Value = '';
            app.TopologicalPathLabel.Visible = 'off';
            app.TopologicalPathEditField.Visible = 'off';
            app.SendTopologicalPathButton.Visible = 'off';
            % Make Map view and load not visible
            app.LoadMapButton.Visible = 'off';
            app.SaveMapButton.Visible = 'off';
            app.MapNameEditField.Visible = 'off';
            app.UIAxes.Visible = 'off';
            % Make Path generation buttons not visible
            app.GeneratePathButton.Visible = 'off';
            app.SendPathButton.Visible = 'off';
            % Make localization button not visible
            app.LocalizeinMapButton.Visible = 'off';
            % Make mapping button not visible
            app.MapOutEnviornmentButton.Visible = 'off';
            % Make bluetooth reading not visible
            app.BTRead.Visible = 'off';
            % Make path generated label not visible
            app.PathGeneratedLabel.Visible = 'off';
            % Make map generated label not visible
            app.MapGeneratedLabel.Visible = 'off';
            % Make kill program button not visible
            app.KillProgramButton.Visible = 'off';
            
            if (value == "Metric Path") 
                % Make start location visible
                app.StartLocationLabel.Visible = 'on';
                app.StartLocationX_TextBox.Visible = 'on';
                app.Label.Visible = 'on';
                app.StartLocationY_TextBox.Visible = 'on';
                app.Label_2.Visible = 'on';
                % Make end location visible
                app.EndLocationLabel.Visible = 'on';
                app.EndLocationX_TextBox.Visible = 'on';
                app.Label_4.Visible = 'on';
                app.EndLocationY_TextBox.Visible = 'on';
                app.Label_3.Visible = 'on';
                % Make Map view and load visible
                app.LoadMapButton.Visible = 'on';
                app.MapNameEditField.Visible = 'on';
                app.UIAxes.Visible = 'on';
                % Make Path generation buttons visible
                app.GeneratePathButton.Visible = 'on';
                app.SendPathButton.Visible = 'on';
                
            elseif (value == "Localization")
                % Make end location visible
                app.EndLocationLabel.Visible = 'on';
                app.EndLocationX_TextBox.Visible = 'on';
                app.Label_4.Visible = 'on';
                app.EndLocationY_TextBox.Visible = 'on';
                app.Label_3.Visible = 'on';
                % Make Map view and load visible
                app.LoadMapButton.Visible = 'on';
                app.MapNameEditField.Visible = 'on';
                app.UIAxes.Visible = 'on';
                % Make localization button visible
                app.LocalizeinMapButton.Visible = 'on';
                app.SendPathButton.Visible = 'on';
                
            elseif (value == "Topological Path")
                % Make Topological Path boxes visible
                app.TopologicalPathLabel.Visible = 'on';
                app.TopologicalPathEditField.Visible = 'on';
                
            elseif (value == "Mapping")
                % Make start location visible
                app.StartLocationLabel.Visible = 'on';
                app.StartLocationX_TextBox.Visible = 'on';
                app.Label.Visible = 'on';
                app.StartLocationY_TextBox.Visible = 'on';
                app.Label_2.Visible = 'on';
                % Make Map view and load visible
                app.LoadMapButton.Visible = 'on';
                app.SaveMapButton.Visible = 'on';
                app.MapNameEditField.Visible = 'on';
                app.UIAxes.Visible = 'on';
                % Make mapping button visible
                app.MapOutEnviornmentButton.Visible = 'on';
                % Make kill program button visible
                app.KillProgramButton.Visible = 'on';
            end
        end

        % Button pushed function: SendTopologicalPathButton
        function SendTopologicalPathButtonPushed(app, event)
            fwrite(app.bt,'~T');
            pause(0.1);
            fwrite(app.bt,append(app.topPath,'-'));
            
            app.BTRead.Visible = 'on';
            app.BTRead.Text = fscanf(app.bt);
        end

        % Value changing function: TopologicalPathEditField
        function TopologicalPathEditFieldValueChanging(app, event)
            app.SendTopologicalPathButton.Visible = 'on';
            app.BTRead.Text = '';
        end

        % Button pushed function: LoadMapButton
        function LoadMapButtonPushed(app, event)
            app.map = load(app.mapTextFileName);
            if(strcmp(app.mapTextFileName,'OGrid2.txt'))
                app.map = ogrid2map(app.map);
            end
            [numRows,numCols] = size(app.map);
            axis(app.UIAxes,[-1 numCols+1 -1 numRows+1])
            app.r1 = rectangle(app.UIAxes,'Position',[0 0 numCols numRows],'LineWidth',2);
            linecount = 1;
            app.delete_complete = false;
            for r = 1:numRows
                for c = 1:numCols
                    if(bitget(app.map(r,c),1)==1)
                        x1 = c-1; x2 = c;
                        y1 = numRows-(r-1); y2 = numRows-(r-1);
                        app.loadMapLines(linecount) = line(app.UIAxes,[x1 x2],[y1 y2],'color','k','LineWidth',2);
                        linecount = linecount + 1;
                    end
                    if(bitget(app.map(r,c),2)==1)
                        x1 = c; x2 = c;
                        y1 = numRows-(r-1); y2 = numRows-r;
                        app.loadMapLines(linecount) = line(app.UIAxes,[x1 x2],[y1 y2],'color','k','LineWidth',2);
                        linecount = linecount + 1;
                    end
                end
            end
        end

        % Value changed function: MapNameEditField
        function MapNameEditFieldValueChanged(app, event)
            app.mapTextFileName = app.MapNameEditField.Value; 
        end

        % Button pushed function: MapOutEnviornmentButton
        function MapOutEnviornmentButtonPushed(app, event)
            app.map = zeros(4,4);
            
            fwrite(app.bt,'~M');
            fwrite(app.bt, [char(str2num(cell2mat(app.xStart)))  char(str2num(cell2mat(app.yStart))) '-']);
            
%             app.drawRect;
            
            startString = '~';
            stopString = '!';
            breakNum = 127;
            
            fwrite(app.bt,'G-');
            
            app.runMapping = true;
            
            while (app.runMapping)
                message = fread(app.bt);
%                 if(~isempty(message))
                drawnow;
                    if (message(1) == double(char(startString)))
                        if (message(2) == breakNum && message(3) == breakNum && message(4) == breakNum)
                            
                            app.MapGeneratedLabel.Visible = 'on';
                            break;
                        end
                        xLoc = message(2);
                        yLoc = message(3);
                        walls = message(4);
                        if (message(5) == double(char(stopString)))
                            mapData(app, xLoc, yLoc, walls);
                        end
                    end 
%                 end
                fwrite(app.bt,'G-');
            end
        end

        % Button pushed function: SendPathButton
        function SendPathButtonPushed(app, event)
%             fwrite(app.bt,'~P');
            write(app.bt,'~P');
            pause(0.1);
%             fwrite(app.bt,append(app.metricPath,'-'));
            write(app.bt,append(app.metricPath,'-'));
        end

        % Button pushed function: GeneratePathButton
        function GeneratePathButtonPushed(app, event)
            % Reset metric path
            app.metricPath = '';
            app.metricPath = findMetricPath(app.map, app.xStart, app.yStart, app.xGoal, app.yGoal);
            app.PathGeneratedLabel.Visible = 'on';
            app.plotMetricPath(app.metricPath);
        end

        % Button pushed function: LocalizeinMapButton
        function LocalizeinMapButtonPushed(app, event)
%             fwrite(app.bt, '~L');
            write(app.bt, '~L');
            pause(0.1);
            
            row1 = char(app.map(1, :));
            row2 = char(app.map(2, :));
            row3 = char(app.map(3, :));
            row4 = char(app.map(4, :));
            
%             fwrite(app.bt, [row1 row2 row3 row4 '-']);
            write(app.bt, [row1 row2 row3 row4 '-']);
            
            configureTerminator(app.bt,double('!')) 
            configureCallback(app.bt,"terminator",@callbackFcn);


%             startString = '~';
%             stopString = '!';
%             breakNum = 127;
%             
%             while(true)
%                 message = fread(app.bt);
%                     if (message(1) == double(char(startString)))
%                         if (message(2) == breakNum && message(3) == breakNum)
%                             break;
%                         end
%                         xLoc = message(2);
%                         yLoc = message(3);
%                         if (message(4) == double(char(stopString)))
%                             path = '';
%                             path = findMetricPath(app.map, xLoc, yLoc, app.xGoal, app.yGoal);
%                             app.PathGeneratedLabel.Visible = 'on';
%                             app.plotMetricPath(path);
%                         end
%                     end 
%             end
        end

        % Value changed function: TopologicalPathEditField
        function TopologicalPathEditFieldValueChanged(app, event)
            app.topPath = app.TopologicalPathEditField.Value;          
        end

        % Button pushed function: ConnectBluetoothButton
        function ConnectBluetoothButtonPushed(app, event)
            app.bt = bluetooth('BRAITENBERG_BT',1);
%             fopen(app.bt);
%             if(app.bt.status == 'open')
                app.ConnectBluetoothButton.Visible = 'off';
                app.BluetoothConnectionSuccessfulLabel.Visible = 'on';
                app.BluetoothConnectionLostLabel.Visible = 'off';
                app.btConnected = true;
%             elseif(app.bt.status == 'closed')
%                 app.ConnectBluetoothButton.Visible = 'on';
%                 app.BluetoothConnectionSuccessfulLabel.Visible = 'off';
%                 app.BluetoothConnectionLostLabel.Visible = 'on';
%                 app.btConnected = false;
%                 fclose(app.bt);
%             end
        end

        % Value changed function: EndLocationX_TextBox
        function EndLocationX_TextBoxValueChanged(app, event)
            app.xGoal = app.EndLocationX_TextBox.Value;
        end

        % Value changed function: EndLocationY_TextBox
        function EndLocationY_TextBoxValueChanged(app, event)
            app.yGoal = app.EndLocationY_TextBox.Value;
        end

        % Value changed function: StartLocationX_TextBox
        function StartLocationX_TextBoxValueChanged(app, event)
            app.xStart = app.StartLocationX_TextBox.Value;
        end

        % Value changed function: StartLocationY_TextBox
        function StartLocationY_TextBoxValueChanged(app, event)
            app.yStart = app.StartLocationY_TextBox.Value;
        end

        % Button pushed function: KillProgramButton
        function KillProgramButtonPushed(app, event)
            app.runMapping = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Map')
            app.UIAxes.Visible = 'off';
            app.StartLocationLabel = uilabel(app.UIFigure);
            app.StartLocationLabel.Visible = 'off';
            app.StartLocationLabel.Text = 'Start Location: ( ';
            app.StartLocationX_TextBox = uitextarea(app.UIFigure);
            app.StartLocationX_TextBox.ValueChangedFcn = createCallbackFcn(app, @StartLocationX_TextBoxValueChanged, true);
            app.StartLocationX_TextBox.Visible = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = ', ';
            app.StartLocationY_TextBox = uitextarea(app.UIFigure);
            app.StartLocationY_TextBox.ValueChangedFcn = createCallbackFcn(app, @StartLocationY_TextBoxValueChanged, true);
            app.StartLocationY_TextBox.Visible = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = ')';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = ')';
            app.EndLocationY_TextBox = uitextarea(app.UIFigure);
            app.EndLocationY_TextBox.ValueChangedFcn = createCallbackFcn(app, @EndLocationY_TextBoxValueChanged, true);
            app.EndLocationY_TextBox.Visible = 'off';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = ', ';
            app.EndLocationX_TextBox = uitextarea(app.UIFigure);
            app.EndLocationX_TextBox.ValueChangedFcn = createCallbackFcn(app, @EndLocationX_TextBoxValueChanged, true);
            app.EndLocationX_TextBox.Visible = 'off';
            app.EndLocationLabel = uilabel(app.UIFigure);
            app.EndLocationLabel.Visible = 'off';
            app.EndLocationLabel.Text = 'End Location: ( ';
            app.SelectModeLabel = uilabel(app.UIFigure);
            app.SelectModeLabel.Text = 'Select Mode';
            app.SelectModeDropDown = uidropdown(app.UIFigure);
            app.SelectModeDropDown.Items = {'Main', 'Topological Path', 'Metric Path', 'Localization', 'Mapping'};
            app.SelectModeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectModeDropDownValueChanged, true);
            app.SelectModeDropDown.Value = 'Main';
            app.TopologicalPathLabel = uilabel(app.UIFigure);
            app.TopologicalPathLabel.Visible = 'off';
            app.TopologicalPathLabel.Text = 'Topological Path:';
            app.TopologicalPathEditField = uieditfield(app.UIFigure, 'text');
            app.TopologicalPathEditField.ValueChangedFcn = createCallbackFcn(app, @TopologicalPathEditFieldValueChanged, true);
            app.TopologicalPathEditField.ValueChangingFcn = createCallbackFcn(app, @TopologicalPathEditFieldValueChanging, true);
            app.TopologicalPathEditField.Visible = 'off';
            app.SendTopologicalPathButton = uibutton(app.UIFigure, 'push');
            app.SendTopologicalPathButton.ButtonPushedFcn = createCallbackFcn(app, @SendTopologicalPathButtonPushed, true);
            app.SendTopologicalPathButton.Visible = 'off';
            app.SendTopologicalPathButton.Text = 'Send Path';
            app.LoadMapButton = uibutton(app.UIFigure, 'push');
            app.LoadMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMapButtonPushed, true);
            app.LoadMapButton.Visible = 'off';
            app.LoadMapButton.Text = 'Load Map';
            app.SaveMapButton = uibutton(app.UIFigure, 'push');
            app.SaveMapButton.Visible = 'off';
            app.SaveMapButton.Text = 'Save Map';
            app.MapNameEditField = uieditfield(app.UIFigure, 'text');
            app.MapNameEditField.ValueChangedFcn = createCallbackFcn(app, @MapNameEditFieldValueChanged, true);
            app.MapNameEditField.Visible = 'off';
            app.GeneratePathButton = uibutton(app.UIFigure, 'push');
            app.GeneratePathButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePathButtonPushed, true);
            app.GeneratePathButton.Visible = 'off';
            app.GeneratePathButton.Text = 'Generate Path';
            app.SendPathButton = uibutton(app.UIFigure, 'push');
            app.SendPathButton.ButtonPushedFcn = createCallbackFcn(app, @SendPathButtonPushed, true);
            app.SendPathButton.Visible = 'off';
            app.SendPathButton.Text = 'Send Path';
            app.LocalizeinMapButton = uibutton(app.UIFigure, 'push');
            app.LocalizeinMapButton.ButtonPushedFcn = createCallbackFcn(app, @LocalizeinMapButtonPushed, true);
            app.LocalizeinMapButton.Visible = 'off';
            app.LocalizeinMapButton.Text = 'Localize in Map';
            app.MapOutEnviornmentButton = uibutton(app.UIFigure, 'push');
            app.MapOutEnviornmentButton.ButtonPushedFcn = createCallbackFcn(app, @MapOutEnviornmentButtonPushed, true);
            app.MapOutEnviornmentButton.Visible = 'off';
            app.MapOutEnviornmentButton.Text = 'Map Out Enviornment';
            app.ConnectBluetoothButton = uibutton(app.UIFigure, 'push');
            app.ConnectBluetoothButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectBluetoothButtonPushed, true);
            app.ConnectBluetoothButton.Visible = 'off';
            app.ConnectBluetoothButton.Text = 'Connect Bluetooth';
            app.BluetoothConnectionSuccessfulLabel = uilabel(app.UIFigure);
            app.BluetoothConnectionSuccessfulLabel.Visible = 'off';
            app.BluetoothConnectionSuccessfulLabel.Text = 'Bluetooth Connection Successful';
            app.BTRead = uilabel(app.UIFigure);
            app.BTRead.Text = '';
            app.PathGeneratedLabel = uilabel(app.UIFigure);
            app.PathGeneratedLabel.Visible = 'off';
            app.PathGeneratedLabel.Text = 'Path Generated';
            app.BluetoothConnectionLostLabel = uilabel(app.UIFigure);
            app.BluetoothConnectionLostLabel.Visible = 'off';
            app.BluetoothConnectionLostLabel.Text = 'Bluetooth Connection Lost';
            app.MapGeneratedLabel = uilabel(app.UIFigure);
            app.MapGeneratedLabel.Visible = 'off';
            app.MapGeneratedLabel.Text = 'Map Generated';
            app.KillProgramButton = uibutton(app.UIFigure, 'push');
            app.KillProgramButton.ButtonPushedFcn = createCallbackFcn(app, @KillProgramButtonPushed, true);
            app.KillProgramButton.Visible = 'off';
            app.KillProgramButton.Text = 'Kill Program';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end